classdef SCADA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ScanABBServerviaOPCDAButton     matlab.ui.control.Button
        ServerLabel                     matlab.ui.control.Label
        ConnectButton                   matlab.ui.control.Button
        ServerstatusDisconnectedLamp_2Label  matlab.ui.control.Label
        ServerstatusDisconnectedLamp_2  matlab.ui.control.Lamp
        DisconnectButton                matlab.ui.control.Button
        ScanvariablesButton             matlab.ui.control.Button
        CANSwitchLabel                  matlab.ui.control.Label
        CANSwitch                       matlab.ui.control.Switch
        BRICKSwitchLabel                matlab.ui.control.Label
        BRICKSwitch                     matlab.ui.control.Switch
        StopscanningButton              matlab.ui.control.Button
        CONVEYORFWDLampLabel            matlab.ui.control.Label
        CONVEYORFWDLamp                 matlab.ui.control.Lamp
        ConnectURServerviaMODBUSTCPIPButton  matlab.ui.control.Button
        ServeraddressLabel              matlab.ui.control.Label
        ServerstatusDisconnectedLampLabel  matlab.ui.control.Label
        ServerstatusDisconnectedLamp    matlab.ui.control.Lamp
        CanavailableLampLabel           matlab.ui.control.Label
        CanavailableLamp                matlab.ui.control.Lamp
        BrickavailableLampLabel         matlab.ui.control.Label
        BrickavailableLamp              matlab.ui.control.Lamp
        DisconnectButton_2              matlab.ui.control.Button
        ABBRobotLabel                   matlab.ui.control.Label
        UniversalRobotLabel_2           matlab.ui.control.Label
        StartURRobotButton              matlab.ui.control.Button
        StopURRobotButton               matlab.ui.control.Button
        CounterTestLabel                matlab.ui.control.Label
        CounterTestLabel_2              matlab.ui.control.Label
    end

    
    properties (Access = private)
        hostInfo;
        da;
        grp1;
        itm1;
        itm2;
        itm3;
        scan;
        m;
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ScanABBServerviaOPCDAButton
        function ScanABBServerviaOPCDAButtonPushed(app, event)
            app.hostInfo = opcserverinfo('localhost');
            app.ServerLabel.Text = append('Server: ', app.hostInfo.ServerID);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.da = opcda('localhost', char(app.hostInfo.ServerID));
            connect(app.da);
            app.ServerstatusDisconnectedLamp_2Label.Text = append('Server status: ', app.da.Status);
            app.ServerstatusDisconnectedLamp_2.Color = 'green';
            app.grp1 = addgroup(app.da);
            app.itm1 = additem(app.grp1, 'DESKTOP-OJGEJRP_Controlador1.IOSYSTEM.IOSIGNALS.sal_Lata', 'logical');
            app.itm2 = additem(app.grp1, 'DESKTOP-OJGEJRP_Controlador1.IOSYSTEM.IOSIGNALS.sal_Brick', 'logical');
            app.itm3 = additem(app.grp1, 'DESKTOP-OJGEJRP_Controlador1.IOSYSTEM.IOSIGNALS.CONVEYOR_FWD', 'logical');
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            disconnect(app.da);
            app.ServerstatusDisconnectedLamp_2Label.Text = append('Server status: ', app.da.Status);
            app.ServerstatusDisconnectedLamp_2.Color = 'red';
            delete(app.da);            
        end

        % Button pushed function: ScanvariablesButton
        function ScanvariablesButtonPushed(app, event)
            app.scan = true;
            oldr = [];
%             counter = 0;
%             app.CounterTestLabel.Text = int2str(counter);
            
            oldr2= -1;
%             counter2 = 0;
%             app.CounterTestLabel_2.Text = int2str(counter2);
            
            while app.scan % ATTENZIONE SI PU0' ESEGUIRE UN SOLO CICLO ALLA VOLTA
                % ABB CHECK
                r = read(app.grp1);
                if not(isequal(r, oldr))
                    if r(1).Value == 0
                        app.CANSwitch.Value = 'Off';
                        write(app.m,'holdingregs',129,0);
                    else
                        app.CANSwitch.Value = 'On';
                    end
     
                    if r(2).Value == 0
                        app.BRICKSwitch.Value = 'Off';
                    else
                        app.BRICKSwitch.Value = 'On';
                    end
        
                    if r(3).Value == 0
                        app.CONVEYORFWDLamp.Color = 'white';
                    else
                        app.CONVEYORFWDLamp.Color = 'yellow';
                    end
                    oldr = r;
%                     counter = counter + 1;
%                     app.CounterTestLabel.Text = int2str(counter);
                end
                
                % UR CHECK
                lataReady = read(app.m,'inputregs',129,1);
                if lataReady ~= oldr2 
                    if lataReady == 0
                        app.CanavailableLamp.Color = 'white';
                    else
                        app.CanavailableLamp.Color = 'yellow';
                        write(app.itm1, 1);
                    end
                    oldr2 = lataReady;
%                     counter2 = counter2 + 1;
%                     app.CounterTestLabel_2.Text = int2str(counter2);   
                end
                pause(0.2);
            end
        end

        % Value changed function: CANSwitch
        function CANSwitchValueChanged(app, event)
            value = app.CANSwitch.Value;
            if strcmp(value, 'Off')
                write(app.itm1, 0);               
            else
                write(app.itm1, 1);
            end
        end

        % Button pushed function: StopscanningButton
        function StopscanningButtonPushed(app, event)
            app.scan = false;
        end

        % Value changed function: BRICKSwitch
        function BRICKSwitchValueChanged(app, event)
            value = app.BRICKSwitch.Value;
            if strcmp(value, 'Off')
                write(app.itm2, 0);               
            else
                write(app.itm2, 1);
            end
        end

        % Button pushed function: 
        % ConnectURServerviaMODBUSTCPIPButton
        function ConnectURServerviaMODBUSTCPIPButtonPushed(app, event)
            app.m = modbus('tcpip', '192.168.242.128', 502);
            app.ServeraddressLabel.Text = append('Server address: ', app.m.DeviceAddress  , '     Port: ', int2str(app.m.Port));          
            app.ServerstatusDisconnectedLampLabel.Text = 'Server status: Connected';
            app.ServerstatusDisconnectedLamp.Color = 'green';
            write(app.m,'holdingregs',130,0);
        end

        % Button pushed function: DisconnectButton_2
        function DisconnectButton_2Pushed(app, event)
            clear m;
            app.ServerstatusDisconnectedLampLabel.Text = 'Server status: Disconnected';
            app.ServerstatusDisconnectedLamp.Color = 'red';
        end

        % Button pushed function: StartURRobotButton
        function StartURRobotButtonPushed(app, event)
            write(app.m,'holdingregs',130,1);
            write(app.m,'holdingregs',129,0);
        end

        % Button pushed function: StopURRobotButton
        function StopURRobotButtonPushed(app, event)
            write(app.m,'holdingregs',130,0);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ScanABBServerviaOPCDAButton = uibutton(app.UIFigure, 'push');
            app.ScanABBServerviaOPCDAButton.ButtonPushedFcn = createCallbackFcn(app, @ScanABBServerviaOPCDAButtonPushed, true);
            app.ScanABBServerviaOPCDAButton.Text = ' Scan ABB Server via OPC DA';
            app.ServerLabel = uilabel(app.UIFigure);
            app.ServerLabel.Text = 'Server:';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ServerstatusDisconnectedLamp_2Label = uilabel(app.UIFigure);
            app.ServerstatusDisconnectedLamp_2Label.Text = 'Server status: Disconnected';
            app.ServerstatusDisconnectedLamp_2 = uilamp(app.UIFigure);
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.ScanvariablesButton = uibutton(app.UIFigure, 'push');
            app.ScanvariablesButton.ButtonPushedFcn = createCallbackFcn(app, @ScanvariablesButtonPushed, true);
            app.ScanvariablesButton.Text = 'Scan variables';
            app.CANSwitchLabel = uilabel(app.UIFigure);
            app.CANSwitchLabel.Text = 'CAN';
            app.CANSwitch = uiswitch(app.UIFigure, 'slider');
            app.CANSwitch.ValueChangedFcn = createCallbackFcn(app, @CANSwitchValueChanged, true);
            app.BRICKSwitchLabel = uilabel(app.UIFigure);
            app.BRICKSwitchLabel.Text = 'BRICK';
            app.BRICKSwitch = uiswitch(app.UIFigure, 'slider');
            app.BRICKSwitch.ValueChangedFcn = createCallbackFcn(app, @BRICKSwitchValueChanged, true);
            app.StopscanningButton = uibutton(app.UIFigure, 'push');
            app.StopscanningButton.ButtonPushedFcn = createCallbackFcn(app, @StopscanningButtonPushed, true);
            app.StopscanningButton.Text = 'Stop scanning';
            app.CONVEYORFWDLampLabel = uilabel(app.UIFigure);
            app.CONVEYORFWDLampLabel.Text = 'CONVEYOR FWD';
            app.CONVEYORFWDLamp = uilamp(app.UIFigure);
            app.ConnectURServerviaMODBUSTCPIPButton = uibutton(app.UIFigure, 'push');
            app.ConnectURServerviaMODBUSTCPIPButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectURServerviaMODBUSTCPIPButtonPushed, true);
            app.ConnectURServerviaMODBUSTCPIPButton.Text = 'Connect UR Server via MODBUS TCP/IP';
            app.ServeraddressLabel = uilabel(app.UIFigure);
            app.ServeraddressLabel.Text = 'Server address:';
            app.ServerstatusDisconnectedLampLabel = uilabel(app.UIFigure);
            app.ServerstatusDisconnectedLampLabel.Text = 'Server status: Disconnected';
            app.ServerstatusDisconnectedLamp = uilamp(app.UIFigure);
            app.CanavailableLampLabel = uilabel(app.UIFigure);
            app.CanavailableLampLabel.Text = 'Can available';
            app.CanavailableLamp = uilamp(app.UIFigure);
            app.BrickavailableLampLabel = uilabel(app.UIFigure);
            app.BrickavailableLampLabel.Text = 'Brick available';
            app.BrickavailableLamp = uilamp(app.UIFigure);
            app.DisconnectButton_2 = uibutton(app.UIFigure, 'push');
            app.DisconnectButton_2.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButton_2Pushed, true);
            app.DisconnectButton_2.Text = 'Disconnect';
            app.ABBRobotLabel = uilabel(app.UIFigure);
            app.ABBRobotLabel.Text = 'ABB Robot';
            app.UniversalRobotLabel_2 = uilabel(app.UIFigure);
            app.UniversalRobotLabel_2.Text = 'Universal Robot';
            app.StartURRobotButton = uibutton(app.UIFigure, 'push');
            app.StartURRobotButton.ButtonPushedFcn = createCallbackFcn(app, @StartURRobotButtonPushed, true);
            app.StartURRobotButton.Text = 'Start UR Robot';
            app.StopURRobotButton = uibutton(app.UIFigure, 'push');
            app.StopURRobotButton.ButtonPushedFcn = createCallbackFcn(app, @StopURRobotButtonPushed, true);
            app.StopURRobotButton.Text = 'Stop UR Robot';
            app.CounterTestLabel = uilabel(app.UIFigure);
            app.CounterTestLabel.Text = 'CounterTest';
            app.CounterTestLabel_2 = uilabel(app.UIFigure);
            app.CounterTestLabel_2.Text = 'CounterTest';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SCADA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end