classdef SCADA_simple_ABBreal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SCADAInterfaceUIFigure          matlab.ui.Figure
        CANSwitchLabel                  matlab.ui.control.Label
        CANSwitch                       matlab.ui.control.Switch
        BRICKSwitchLabel                matlab.ui.control.Label
        BRICKSwitch                     matlab.ui.control.Switch
        UR3Panel                        matlab.ui.container.Panel
        StartURRobotButton              matlab.ui.control.Button
        StopURRobotButton               matlab.ui.control.Button
        StartURLamp                     matlab.ui.control.Lamp
        ObjsensorsurButton              matlab.ui.control.Button
        ConexionesPanel                 matlab.ui.container.Panel
        ABBOPCDAPanel                   matlab.ui.container.Panel
        ScanABBServerButton             matlab.ui.control.Button
        ConnectButton                   matlab.ui.control.Button
        ServerLabel                     matlab.ui.control.Label
        OPCstatusDisconnectedLampLabel  matlab.ui.control.Label
        OPCstatusDisconnectedLamp       matlab.ui.control.Lamp
        DisconnectButton                matlab.ui.control.Button
        IPAddressEditFieldLabel         matlab.ui.control.Label
        IPAddressEditField_ABB          matlab.ui.control.EditField
        SimuladoCheckBox                matlab.ui.control.CheckBox
        UR3ModbusTCOIPPanel             matlab.ui.container.Panel
        ConnectButton_2                 matlab.ui.control.Button
        ServeraddressLabel              matlab.ui.control.Label
        ModbusstatusDisconnectedLampLabel  matlab.ui.control.Label
        ModbusstatusDisconnectedLamp    matlab.ui.control.Lamp
        DisconnectButton_2              matlab.ui.control.Button
        IPAddressEditField_2Label       matlab.ui.control.Label
        IPAddressEditField_UR           matlab.ui.control.EditField
        PLCPanel                        matlab.ui.container.Panel
        ObjDisponibleButton             matlab.ui.control.Button
        IPAddressEditField_2Label_2     matlab.ui.control.Label
        IPAddressEditField_UR_2         matlab.ui.control.EditField
        ConnectButton_3                 matlab.ui.control.Button
        DisconnectButton_3              matlab.ui.control.Button
        PLCstatusDisconnectedLampLabel  matlab.ui.control.Label
        PLCstatusDisconnectedLamp       matlab.ui.control.Lamp
        EstadoclulaPanel                matlab.ui.container.Panel
        LataLampLabel                   matlab.ui.control.Label
        LataLamp                        matlab.ui.control.Lamp
        BrickLampLabel                  matlab.ui.control.Label
        BrickLamp                       matlab.ui.control.Lamp
        NlatasEditField                 matlab.ui.control.NumericEditField
        NbricksEditField                matlab.ui.control.NumericEditField
        CONVEYORFWDLampLabel            matlab.ui.control.Label
        CONVEYORFWDLamp                 matlab.ui.control.Lamp
        CONVEYORBWDLampLabel            matlab.ui.control.Label
        CONVEYORBWDLamp                 matlab.ui.control.Lamp
        SealesPanel                     matlab.ui.container.Panel
        ScanvariablesPanel              matlab.ui.container.Panel
        ScanvariablesButton             matlab.ui.control.Button
        StopscanningButton              matlab.ui.control.Button
        ScanLamp                        matlab.ui.control.Lamp
        LataButton                      matlab.ui.control.Button
        BrickButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        hostInfo;
        da;
        grp1;
        itm_sal_lata;
        itm_sal_brick;
        itm_conveyor_fwd;
        itm_conveyor_bwd;
        itm_objeto_verificado;
        itm_sal_obj_nuevo;
        itm_conveyor_obj_sur;
        scan;
        m;
        nlatas=0;
        nbricks=0;% Description
        Property11 % Description
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ScanLamp.Color='red';
            app.StartURLamp.Color='red';
        end

        % Button pushed function: ScanABBServerButton
        function ScanABBServerButtonPushed(app, event)
            %app.hostInfo = opcserverinfo('158.42.16.79');
            %app.hostInfo = opcserverinfo('158.42.206.7');
            
            dir_ip_ABB=app.IPAddressEditField_ABB.Value;
            app.hostInfo = opcserverinfo(dir_ip_ABB);
            app.hostInfo = opcserverinfo('localhost')
            app.ServerLabel.Text = append('Server: ', app.hostInfo.ServerID);
            
%             if app.SimuladoCheckBox.Value==1
%                 app.ServerLabel.Text = append('Server: ', app.hostInfo.ServerID{3});
%             else
%                 app.ServerLabel.Text = append('Server: ', app.hostInfo.ServerID{2});
%             end


        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            %app.da = opcda('158.42.16.79', char(app.hostInfo.ServerID));
            %app.da = opcda('158.42.206.7', char(app.hostInfo.ServerID{2}));
            
            dir_ip_ABB=app.IPAddressEditField_ABB.Value;
            dir_ip_ABB = 'localhost'
            app.da = opcda(dir_ip_ABB, char(app.hostInfo.ServerID));
            
%             if app.SimuladoCheckBox.Value==1
%                 app.da = opcda(dir_ip_ABB, char(app.hostInfo.ServerID{3}));
%             else
%                 app.da = opcda(dir_ip_ABB, char(app.hostInfo.ServerID{2}));
%             end
            
            
           % disconnect(app.da);
            connect(app.da);
            app.OPCstatusDisconnectedLampLabel.Text = append('Server status: ', app.da.Status);
            app.OPCstatusDisconnectedLamp.Color = 'green';
            app.grp1 = addgroup(app.da);
            path1 = serveritems(app.da,'*sal_Lata');
            path2 = serveritems(app.da,'*sal_Brick');
            path3 = serveritems(app.da,'*CONVEYOR_FWD')
            path4 = serveritems(app.da,'*CONVEYOR_BWD')
            path5 = serveritems(app.da,'*objeto_Verificado');
            path6 = serveritems(app.da,'*sal_obj_nuevo');
            path7 = serveritems(app.da,'*CONVEYOR_OBJ_SUR');
            
            %sal_Lata sólo hay 1 señal. Se coge directamente
            app.itm_sal_lata = additem(app.grp1, path1);
            
            %if app.SimuladoCheckBox.Value==1
            app.itm_sal_brick = additem(app.grp1, path2);
            app.itm_conveyor_fwd = additem(app.grp1, path3);
%             else
%                 %sal_brick hay varias: 90grados, 45grados, 0grados: cogemos la 3
%                 app.itm_sal_brick = additem(app.grp1, path2(3));
%                 %CONVEYOR hay varios: cogemos la FWD
%                 app.itm_conveyor_fwd = additem(app.grp1, path3(3));
%             end
           app.itm_conveyor_bwd = additem(app.grp1, path4);
           app.itm_objeto_verificado= additem(app.grp1, path5);
           app.itm_sal_obj_nuevo= additem(app.grp1, path6);             
           app.itm_conveyor_obj_sur=additem(app.grp1, path7);
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.scan = false; 
            app.ScanLamp.Color='red';
            pause(0.2);
            disconnect(app.da);
            app.OPCstatusDisconnectedLampLabel.Text = append('Server status: ', app.da.Status);
            app.OPCstatusDisconnectedLamp.Color = 'red';
            delete(app.da);            
        end

        % Button pushed function: ScanvariablesButton
        function ScanvariablesButtonPushed(app, event)
            app.scan = true;
            app.ScanLamp.Color='green';
            
            while app.scan 
                % leemos las señales del ABB
                conveyorfwd=read(app.itm_conveyor_fwd);
                conveyorbwd=read(app.itm_conveyor_bwd);                
                obj_en_sur=read(app.itm_conveyor_obj_sur);
                
                % ABB ha detectado un objeto en el sensor sur: se lo
                % decimos al UR
                if obj_en_sur.Value==1
                    write(app.m,'holdingregs',131,1);
                else
                    write(app.m,'holdingregs',131,0);
                end
               
                
                if conveyorfwd.Value==1
                    app.CONVEYORFWDLamp.Color='green';
                else
                    app.CONVEYORFWDLamp.Color='red';
                end
                
                if conveyorbwd.Value==1
                    app.CONVEYORBWDLamp.Color='green';
                else
                    app.CONVEYORBWDLamp.Color='red';
                end
                
                % leemos las señales del UR
                lata_Ready = read(app.m,'inputregs',132,1);
                Brick_Ready = read(app.m,'inputregs',133,1);
                obj_verificado = read(app.m,'inputregs',134,1);
                
                if obj_verificado
                    write(app.itm_objeto_verificado, 1);                   
                    write(app.m,'holdingregs',134,0);
%                     app.LataLamp.Color = 'green';
%                     app.BrickLamp.Color = 'white';
%                     numero=app.NlatasEditField.Value;
%                     app.NlatasEditField.Value=numero+1;
                end
                                
                if lata_Ready
                    write(app.itm_sal_lata, 1);
                    write(app.m,'holdingregs',132,0);
                    app.LataLamp.Color = 'green';
                    app.BrickLamp.Color = 'white';
                    numero=app.NlatasEditField.Value;
                    app.NlatasEditField.Value=numero+1;
                end
                
                if Brick_Ready
                    write(app.itm_sal_brick, 1);
                    write(app.m,'holdingregs',133,0);
                    app.LataLamp.Color = 'white';
                    app.BrickLamp.Color = 'green';
                    numero=app.NbricksEditField.Value;
                    app.NbricksEditField.Value=numero+1;
                end 
                                
                pause(0.2);
            end
        end

        % Value changed function: CANSwitch
        function CANSwitchValueChanged(app, event)
            value = app.CANSwitch.Value;
%             if strcmp(value, 'Off')
%                 write(app.itm_sal_lata, 0);               
%             else
%                 write(app.itm_sal_lata, 1);
%             end
        end

        % Button pushed function: StopscanningButton
        function StopscanningButtonPushed(app, event)
            app.scan = false;
            app.ScanLamp.Color='red';
        end

        % Value changed function: BRICKSwitch
        function BRICKSwitchValueChanged(app, event)
            value = app.BRICKSwitch.Value;
%             if strcmp(value, 'Off')
%                 write(app.itm_sal_brick, 0);               
%             else
%                 write(app.itm_sal_brick, 1);
%            end
        end

        % Button pushed function: ConnectButton_2
        function ConnectButton_2Pushed(app, event)
            
            dir_ip_UR=app.IPAddressEditField_UR.Value;
            
%             if app.SimuladoCheckBox.Value==1
%                 app.da = opcda(dir_ip_ABB, char(app.hostInfo.ServerID));
%             else
%                 app.da = opcda(dir_ip_ABB, char(app.hostInfo.ServerID{2}));
%             end
        
            % el UR simulado está en la dir IP: 192.168.139.128
            %app.m = modbus('tcpip', '192.168.139.128', 502);
            
            %app.m = modbus('tcpip', '158.42.206.10', 502);
            
            app.m = modbus('tcpip', dir_ip_UR, 502);


            app.ServeraddressLabel.Text = append('Server address: ', app.m.DeviceAddress  , '     Port: ', int2str(app.m.Port));          
            app.ModbusstatusDisconnectedLampLabel.Text = 'Server status: Connected';
            app.ModbusstatusDisconnectedLamp.Color = 'green';
            write(app.m,'holdingregs',130,0);
        end

        % Button pushed function: DisconnectButton_2
        function DisconnectButton_2Pushed(app, event)
            app.scan = false; 
            app.ScanLamp.Color='red';
            pause(0.2);    
            clear m;
            app.ModbusstatusDisconnectedLampLabel.Text = 'Server status: Disconnected';
            app.ModbusstatusDisconnectedLamp.Color = 'red';
        end

        % Button pushed function: StartURRobotButton
        function StartURRobotButtonPushed(app, event)
            write(app.m,'holdingregs',129,1);
            app.StartURLamp.Color='green';
        end

        % Button pushed function: StopURRobotButton
        function StopURRobotButtonPushed(app, event)
            write(app.m,'holdingregs',129,0);
            app.StartURLamp.Color='red';
        end

        % Button pushed function: LataButton
        function LataButtonPushed(app, event)
            write(app.itm_sal_lata, 1);
        end

        % Button pushed function: BrickButton
        function BrickButtonPushed(app, event)
           write(app.itm_sal_brick, 1);
        end

        % Button pushed function: ObjDisponibleButton
        function ObjDisponibleButtonPushed(app, event)
           %write(app.m,'holdingregs',130,1);
           % hay un objeto nuevo: se lo indicamos al ABB
           write(app.itm_sal_obj_nuevo, 1);
        end

        % Button pushed function: ObjsensorsurButton
        function ObjsensorsurButtonPushed(app, event)
           write(app.m,'holdingregs',131,1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SCADAInterfaceUIFigure = uifigure('Visible', 'off');
            app.SCADAInterfaceUIFigure.Name = 'SCADA Interface';
            app.CANSwitchLabel = uilabel(app.SCADAInterfaceUIFigure);
            app.CANSwitchLabel.Visible = 'off';
            app.CANSwitchLabel.Text = 'CAN';
            app.CANSwitch = uiswitch(app.SCADAInterfaceUIFigure, 'slider');
            app.CANSwitch.ValueChangedFcn = createCallbackFcn(app, @CANSwitchValueChanged, true);
            app.CANSwitch.Visible = 'off';
            app.BRICKSwitchLabel = uilabel(app.SCADAInterfaceUIFigure);
            app.BRICKSwitchLabel.Visible = 'off';
            app.BRICKSwitchLabel.Text = 'BRICK';
            app.BRICKSwitch = uiswitch(app.SCADAInterfaceUIFigure, 'slider');
            app.BRICKSwitch.ValueChangedFcn = createCallbackFcn(app, @BRICKSwitchValueChanged, true);
            app.BRICKSwitch.Visible = 'off';
            app.UR3Panel = uipanel(app.SCADAInterfaceUIFigure);
            app.UR3Panel.Title = 'UR3';
            app.StartURRobotButton = uibutton(app.UR3Panel, 'push');
            app.StartURRobotButton.ButtonPushedFcn = createCallbackFcn(app, @StartURRobotButtonPushed, true);
            app.StartURRobotButton.Text = 'Start UR Robot';
            app.StopURRobotButton = uibutton(app.UR3Panel, 'push');
            app.StopURRobotButton.ButtonPushedFcn = createCallbackFcn(app, @StopURRobotButtonPushed, true);
            app.StopURRobotButton.Text = 'Stop UR Robot';
            app.StartURLamp = uilamp(app.UR3Panel);
            app.ObjsensorsurButton = uibutton(app.UR3Panel, 'push');
            app.ObjsensorsurButton.ButtonPushedFcn = createCallbackFcn(app, @ObjsensorsurButtonPushed, true);
            app.ObjsensorsurButton.Text = 'Obj sensor sur';
            app.ConexionesPanel = uipanel(app.SCADAInterfaceUIFigure);
            app.ConexionesPanel.Title = 'Conexiones';
            app.ABBOPCDAPanel = uipanel(app.ConexionesPanel);
            app.ABBOPCDAPanel.Title = 'ABB (OPC DA)';
            app.ScanABBServerButton = uibutton(app.ABBOPCDAPanel, 'push');
            app.ScanABBServerButton.ButtonPushedFcn = createCallbackFcn(app, @ScanABBServerButtonPushed, true);
            app.ScanABBServerButton.Text = ' Scan ABB Server ';
            app.ConnectButton = uibutton(app.ABBOPCDAPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ServerLabel = uilabel(app.ABBOPCDAPanel);
            app.ServerLabel.Text = 'Server:';
            app.OPCstatusDisconnectedLampLabel = uilabel(app.ABBOPCDAPanel);
            app.OPCstatusDisconnectedLampLabel.Text = 'OPC status: Disconnected';
            app.OPCstatusDisconnectedLamp = uilamp(app.ABBOPCDAPanel);
            app.DisconnectButton = uibutton(app.ABBOPCDAPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.IPAddressEditFieldLabel = uilabel(app.ABBOPCDAPanel);
            app.IPAddressEditFieldLabel.Text = 'IP Address';
            app.IPAddressEditField_ABB = uieditfield(app.ABBOPCDAPanel, 'text');
            app.IPAddressEditField_ABB.Value = '127.0.0.1';
            app.SimuladoCheckBox = uicheckbox(app.ABBOPCDAPanel);
            app.SimuladoCheckBox.Text = 'Simulado';
            app.SimuladoCheckBox.Value = true;
            app.UR3ModbusTCOIPPanel = uipanel(app.ConexionesPanel);
            app.UR3ModbusTCOIPPanel.Title = 'UR3 (Modbus TCO/IP)';
            app.ConnectButton_2 = uibutton(app.UR3ModbusTCOIPPanel, 'push');
            app.ConnectButton_2.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_2Pushed, true);
            app.ConnectButton_2.Text = 'Connect ';
            app.ServeraddressLabel = uilabel(app.UR3ModbusTCOIPPanel);
            app.ServeraddressLabel.Text = 'Server address:';
            app.ModbusstatusDisconnectedLampLabel = uilabel(app.UR3ModbusTCOIPPanel);
            app.ModbusstatusDisconnectedLampLabel.Text = 'Modbus status: Disconnected';
            app.ModbusstatusDisconnectedLamp = uilamp(app.UR3ModbusTCOIPPanel);
            app.DisconnectButton_2 = uibutton(app.UR3ModbusTCOIPPanel, 'push');
            app.DisconnectButton_2.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButton_2Pushed, true);
            app.DisconnectButton_2.Text = 'Disconnect';
            app.IPAddressEditField_2Label = uilabel(app.UR3ModbusTCOIPPanel);
            app.IPAddressEditField_2Label.Text = 'IP Address';
            app.IPAddressEditField_UR = uieditfield(app.UR3ModbusTCOIPPanel, 'text');
            app.IPAddressEditField_UR.Value = '192.168.56.128';
            app.PLCPanel = uipanel(app.ConexionesPanel);
            app.PLCPanel.Title = 'PLC';
            app.ObjDisponibleButton = uibutton(app.PLCPanel, 'push');
            app.ObjDisponibleButton.ButtonPushedFcn = createCallbackFcn(app, @ObjDisponibleButtonPushed, true);
            app.ObjDisponibleButton.Text = 'Obj Disponible';
            app.IPAddressEditField_2Label_2 = uilabel(app.PLCPanel);
            app.IPAddressEditField_2Label_2.Text = 'IP Address';
            app.IPAddressEditField_UR_2 = uieditfield(app.PLCPanel, 'text');
            app.IPAddressEditField_UR_2.Value = '192.168.0.108';
            app.ConnectButton_3 = uibutton(app.PLCPanel, 'push');
            app.ConnectButton_3.Text = 'Connect ';
            app.DisconnectButton_3 = uibutton(app.PLCPanel, 'push');
            app.DisconnectButton_3.Text = 'Disconnect';
            app.PLCstatusDisconnectedLampLabel = uilabel(app.PLCPanel);
            app.PLCstatusDisconnectedLampLabel.Text = 'PLC status: Disconnected';
            app.PLCstatusDisconnectedLamp = uilamp(app.PLCPanel);
            app.EstadoclulaPanel = uipanel(app.SCADAInterfaceUIFigure);
            app.EstadoclulaPanel.Title = 'Estado célula';
            app.LataLampLabel = uilabel(app.EstadoclulaPanel);
            app.LataLampLabel.Text = 'Lata';
            app.LataLamp = uilamp(app.EstadoclulaPanel);
            app.BrickLampLabel = uilabel(app.EstadoclulaPanel);
            app.BrickLampLabel.Text = 'Brick';
            app.BrickLamp = uilamp(app.EstadoclulaPanel);
            app.NlatasEditField = uieditfield(app.EstadoclulaPanel, 'numeric');
            app.NbricksEditField = uieditfield(app.EstadoclulaPanel, 'numeric');
            app.CONVEYORFWDLampLabel = uilabel(app.EstadoclulaPanel);
            app.CONVEYORFWDLampLabel.Text = 'CONVEYOR FWD';
            app.CONVEYORFWDLamp = uilamp(app.EstadoclulaPanel);
            app.CONVEYORBWDLampLabel = uilabel(app.EstadoclulaPanel);
            app.CONVEYORBWDLampLabel.Text = 'CONVEYOR BWD';
            app.CONVEYORBWDLamp = uilamp(app.EstadoclulaPanel);
            app.SealesPanel = uipanel(app.SCADAInterfaceUIFigure);
            app.SealesPanel.Title = 'Señales';
            app.ScanvariablesPanel = uipanel(app.SealesPanel);
            app.ScanvariablesPanel.Title = 'Scan variables';
            app.ScanvariablesButton = uibutton(app.ScanvariablesPanel, 'push');
            app.ScanvariablesButton.ButtonPushedFcn = createCallbackFcn(app, @ScanvariablesButtonPushed, true);
            app.ScanvariablesButton.Text = 'Scan variables';
            app.StopscanningButton = uibutton(app.ScanvariablesPanel, 'push');
            app.StopscanningButton.ButtonPushedFcn = createCallbackFcn(app, @StopscanningButtonPushed, true);
            app.StopscanningButton.Text = 'Stop scanning';
            app.ScanLamp = uilamp(app.ScanvariablesPanel);
            app.LataButton = uibutton(app.SealesPanel, 'push');
            app.LataButton.ButtonPushedFcn = createCallbackFcn(app, @LataButtonPushed, true);
            app.LataButton.Text = 'Lata';
            app.BrickButton = uibutton(app.SealesPanel, 'push');
            app.BrickButton.ButtonPushedFcn = createCallbackFcn(app, @BrickButtonPushed, true);
            app.BrickButton.Text = 'Brick';
            % Show the figure after all components are created
            app.SCADAInterfaceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SCADA_simple_ABBreal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SCADAInterfaceUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SCADAInterfaceUIFigure)
        end
    end
end